classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CannyButton                matlab.ui.control.Button
        SobelButton                matlab.ui.control.Button
        SegmentedImageLabel        matlab.ui.control.Label
        EdgeImageLabel             matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        SelectOperatorLabel        matlab.ui.control.Label
        RobertsButton              matlab.ui.control.Button
        PrewittButton              matlab.ui.control.Button
        LaplacianofGaussianButton  matlab.ui.control.Button
        LaplaceButton              matlab.ui.control.Button
        UploadButton               matlab.ui.control.Button
        ObjectSegmentationLabel    matlab.ui.control.Label
        SegmentedImageAxes         matlab.ui.control.UIAxes
        EdgeImageAxes              matlab.ui.control.UIAxes
        OriginalImageAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        oriImage % Original image, meant to be processed
    end
    
    methods (Access = private)
        function uploadPhoto(app)
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            imagesc(app.OriginalImageAxes,ImageFile);
            app.oriImage = ImageFile;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.OriginalImageAxes.Colormap = gray(256);
            axis(app.OriginalImageAxes, 'image');
            app.OriginalImageAxes.Visible = 'off';
            app.EdgeImageAxes.Colormap = gray(256);
            axis(app.EdgeImageAxes, 'image');
            app.EdgeImageAxes.Visible = 'off';
            app.SegmentedImageAxes.Colormap = gray(256);
            axis(app.SegmentedImageAxes, 'image');
            app.SegmentedImageAxes.Visible = 'off';

            app.LaplaceButton.Enable = 'off';
            app.LaplacianofGaussianButton.Enable = 'off';
            app.SobelButton.Enable = 'off';
            app.PrewittButton.Enable = 'off';
            app.RobertsButton.Enable = 'off';
            app.CannyButton.Enable = 'off';

            app.SelectOperatorLabel.Enable = 'off';
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            cla(app.OriginalImageAxes);
            cla(app.EdgeImageAxes);
            cla(app.SegmentedImageAxes);
            app.OriginalImageAxes.Visible = 'on';

            app.LaplaceButton.Enable = 'on';
            app.LaplacianofGaussianButton.Enable = 'on';
            app.SobelButton.Enable = 'on';
            app.PrewittButton.Enable = 'on';
            app.RobertsButton.Enable = 'on';
            app.CannyButton.Enable = 'on';

            app.SelectOperatorLabel.Enable = 'on';

            uploadPhoto(app);
        end

        % Button pushed function: LaplaceButton
        function LaplaceButtonPushed(app, event)
             % Call function
            [edge_image, segmented_image] = segmentImage(app.oriImage, 'lap');

            % Show images to axis
            app.EdgeImageLabel.Visible = 'on';
            app.SegmentedImageLabel.Visible = 'on';
            imagesc(app.EdgeImageAxes,edge_image);
            imagesc(app.SegmentedImageAxes,segmented_image);
        end

        % Button pushed function: LaplacianofGaussianButton
        function LaplacianofGaussianButtonPushed(app, event)
             % Call function
            [edge_image, segmented_image] = segmentImage(app.oriImage, 'log');

            % Show images to axis
            app.EdgeImageLabel.Visible = 'on';
            app.SegmentedImageLabel.Visible = 'on';
            imagesc(app.EdgeImageAxes,edge_image);
            imagesc(app.SegmentedImageAxes,segmented_image);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
             % Call function
            [edge_image, segmented_image] = segmentImage(app.oriImage, 'prewitt');

            % Show images to axis
            app.EdgeImageLabel.Visible = 'on';
            app.SegmentedImageLabel.Visible = 'on';
            imagesc(app.EdgeImageAxes,edge_image);
            imagesc(app.SegmentedImageAxes,segmented_image);
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            % Call function
            [edge_image, segmented_image] = segmentImage(app.oriImage, 'roberts');

            % Show images to axis
            app.EdgeImageLabel.Visible = 'on';
            app.SegmentedImageLabel.Visible = 'on';
            imagesc(app.EdgeImageAxes,edge_image);
            imagesc(app.SegmentedImageAxes,segmented_image);
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
             % Call function
            [edge_image, segmented_image] = segmentImage(app.oriImage, 'sobel');

            % Show images to axis
            app.EdgeImageLabel.Visible = 'on';
            app.SegmentedImageLabel.Visible = 'on';
            imagesc(app.EdgeImageAxes,edge_image);
            imagesc(app.SegmentedImageAxes,segmented_image);
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
             % Call function
            [edge_image, segmented_image] = segmentImage(app.oriImage, 'canny');

            % Show images to axis
            app.EdgeImageLabel.Visible = 'on';
            app.SegmentedImageLabel.Visible = 'on';
            imagesc(app.EdgeImageAxes,edge_image);
            imagesc(app.SegmentedImageAxes,segmented_image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            app.OriginalImageAxes.XTick = [];
            app.OriginalImageAxes.XTickLabel = {'[ ]'};
            app.OriginalImageAxes.YTick = [];
            app.EdgeImageAxes = uiaxes(app.UIFigure);
            app.EdgeImageAxes.XTick = [];
            app.EdgeImageAxes.XTickLabel = {'[ ]'};
            app.EdgeImageAxes.YTick = [];
            app.SegmentedImageAxes = uiaxes(app.UIFigure);
            app.SegmentedImageAxes.XTick = [];
            app.SegmentedImageAxes.XTickLabel = {'[ ]'};
            app.SegmentedImageAxes.YTick = [];
            app.ObjectSegmentationLabel = uilabel(app.UIFigure);
            app.ObjectSegmentationLabel.Text = 'Object Segmentation';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload image';
            app.LaplaceButton = uibutton(app.UIFigure, 'push');
            app.LaplaceButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceButtonPushed, true);
            app.LaplaceButton.Text = 'Laplace';
            app.LaplacianofGaussianButton = uibutton(app.UIFigure, 'push');
            app.LaplacianofGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianofGaussianButtonPushed, true);
            app.LaplacianofGaussianButton.Text = 'Laplacian of Gaussian';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uibutton(app.UIFigure, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.SelectOperatorLabel = uilabel(app.UIFigure);
            app.SelectOperatorLabel.Text = 'Select Operator';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Visible = 'off';
            app.OriginalImageLabel.Text = 'Original Image';
            app.EdgeImageLabel = uilabel(app.UIFigure);
            app.EdgeImageLabel.Visible = 'off';
            app.EdgeImageLabel.Text = 'Edge Image';
            app.SegmentedImageLabel = uilabel(app.UIFigure);
            app.SegmentedImageLabel.Visible = 'off';
            app.SegmentedImageLabel.Text = 'Segmented Image';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.CannyButton = uibutton(app.UIFigure, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end